classdef alignHistology2OCT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ReverttoStackAlignmentButton  matlab.ui.control.Button
        AutoFineAlignbySurfaceButton  matlab.ui.control.Button
        ReverttoLastSaveButton        matlab.ui.control.Button
        OverlaySettingsPanel          matlab.ui.container.Panel
        LinesSwitch                   matlab.ui.control.Switch
        LinesSwitchLabel              matlab.ui.control.Label
        BaseImageSwitch               matlab.ui.control.Switch
        BaseImageSwitchLabel          matlab.ui.control.Label
        TransparancyKnob              matlab.ui.control.Knob
        TransparancyKnobLabel         matlab.ui.control.Label
        AlongPlaneAlignmentPanel      matlab.ui.container.Panel
        ZTranslationumSpinner         matlab.ui.control.Spinner
        ZTranslationumSpinnerLabel    matlab.ui.control.Label
        XTranslationumSpinner         matlab.ui.control.Spinner
        XTranslationumSpinnerLabel    matlab.ui.control.Label
        RotationdegSpinner            matlab.ui.control.Spinner
        RotationdegSpinnerLabel       matlab.ui.control.Label
        ExpansionorShrinkageSpinner   matlab.ui.control.Spinner
        ExpansionorShrinkageLabel     matlab.ui.control.Label
        CrossPlaneAlignmentPanel      matlab.ui.container.Panel
        xumLabel                      matlab.ui.control.Label
        DefaultPositionLabel          matlab.ui.control.Label
        StepSizeDropDown              matlab.ui.control.DropDown
        StepSizeDropDownLabel         matlab.ui.control.Label
        YPositionumSpinner            matlab.ui.control.Spinner
        YPositionumSpinnerLabel       matlab.ui.control.Label
        LoadSaveDataPanel             matlab.ui.container.Panel
        RectifyStackButton            matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        SectionDropDown               matlab.ui.control.DropDown
        SectionDropDownLabel          matlab.ui.control.Label
        SaveSectionButton             matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        SubjectPathEditField          matlab.ui.control.EditField
        SubjectPathEditFieldLabel     matlab.ui.control.Label
        FiguresPanel                  matlab.ui.container.Panel
        UIAxesHistology               matlab.ui.control.UIAxes
        UIAxesOCT                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        subjectPath = ''; % Subject's path been loaded
        
        jsons = []; % All jsons files that will be needed
        slidePath = [];
        
        histologyFilePath = '';
        histologyIm = [];
        
        brightFieldFilePath = '';
        brightFieldIm = [];
        
        FMLinesFilePath = '';
        FMLinesIm = [];
        
        loadCashTimmer =[];
    end
    
    methods (Access = private)
        
        function setKnobesToTransform(app, histologyToOCTT)
            xOut = app.jsons.sectionIterationConfig.data_um.x.values;
            zOut = app.jsons.sectionIterationConfig.data_um.z.values;
            dx = diff(xOut(1:2));
            dz = diff(zOut(1:2));
            if std(diff(xOut))>1e-3 || std(diff(zOut))>1e-3 || abs(dx-dz)>1e-3
                error('Unimplemented here');
            end
            
            [app.ExpansionorShrinkageSpinner.Value, ...
                app.RotationdegSpinner.Value,...
                app.XTranslationumSpinner.Value,...
                app.ZTranslationumSpinner.Value] = transform2Knobes(histologyToOCTT,app.jsons.slideConfig.data.FM.pixelSize_um,dx);
        end
        
        % Helpfer function to convert deafult position to knobes position
        function setKnobesToDefaultPosition(app, md)
            % md can be 0 - meaning revert to last save or 1 meaning force
            % revert to stack alignment (single planeFit)
            % Figure out output parameters
            
            if ~exist('md','var')
                md = 0; % Default
            end
            
            if (isempty(app.jsons.sectionIterationConfig.data))
                error('Cannot load section iteration, did you reslice before running this script?')
            end
            xOut = app.jsons.sectionIterationConfig.data_um.x.values;
            zOut = app.jsons.sectionIterationConfig.data_um.z.values;
            dx = diff(xOut(1:2));
            dz = diff(zOut(1:2));
            if std(diff(xOut))>1e-3 || std(diff(zOut))>1e-3 || abs(dx-dz)>1e-3
                error('Unimplemented here');
            end
            
            if (isfield(app.jsons.slideConfig.data.FM,'singlePlaneFit_FineAligned') && ...
                    ~isempty(app.jsons.slideConfig.data.FM.singlePlaneFit_FineAligned) && md == 0)
                %Fine alignment already computed, set this as default
                singlePlaneFit = app.jsons.slideConfig.data.FM.singlePlaneFit_FineAligned;
                u = singlePlaneFit.u;
                v = singlePlaneFit.v;
                h = singlePlaneFit.h;
            else
                % Fine alignment wasn't computed, so single plane comes
                % from best estimate of single plane
                if isfield(app.jsons.slideConfig.data.FM,'singlePlaneFit')  && ...
                        ~isempty(app.jsons.slideConfig.data.FM.singlePlaneFit)
                    singlePlaneFit = app.jsons.slideConfig.data.FM.singlePlaneFit;
                    u = singlePlaneFit.u;
                    v = singlePlaneFit.v;
                    h = singlePlaneFit.h;
                else
                    % Use default values if single plane fit was never
                    % computed
                    u = [1 0 0];
                    v = [0 0 1];
                    h = [0 0 0];
                end
                
                % Correct u&v to have the average pixel size [mm]
                scaleFactor = {app.jsons.stackConfig.data.stackAlignment.scaleFactor};
                scaleFactor = scaleFactor{app.jsons.sectionIteration};
                u = u/norm(u)*app.jsons.slideConfig.data.FM.pixelSize_um/scaleFactor*1e-3;
                v = v/norm(v)*app.jsons.slideConfig.data.FM.pixelSize_um/scaleFactor*1e-3;
            end
            
            % Convert OCT parameters to a transform between OCT and
            % histology
            histologyToOCTT = octSlideToTransform( ...
                u, ...
                v, ...
                h, ...
                app.jsons.sectionIterationConfig.data.new2OriginalAffineTransform, ...
                xOut(1)*1e-3, ...
                zOut(1)*1e-3, ...
                app.jsons.slideConfig.data.FM.pixelSize_um, ...
                dx);
            
            setKnobesToTransform(app, histologyToOCTT)
            
            % Set y position um
            if isfield(app.jsons.slideConfig.data.FM,'singlePlaneFit_FineAligned')
                %Fine alignment already computed, set this as default
                y_um  = ...
                    app.jsons.slideConfig.data.FM.singlePlaneFit_FineAligned.d*1e3;
            else
                % Fine alignment wasn't computed, so single plane comes
                % from best estimate of single plane
                tmp = arrayfun(@(x)(x.planeDistanceFromOCTOrigin_um(:)'), ...
                app.jsons.stackConfig.data.stackAlignment,'UniformOutput',false);
                tmp = [tmp{:}];
                y_um = (tmp(app.jsons.sectionIndexInStack));
            end
            app.YPositionumSpinner.Value = round(y_um);
            app.xumLabel.Text = sprintf('%.0f um',y_um);
            
            % QA the transform
            if ~isHistologyToOCTTMakeSense(histologyToOCTT,[length(zOut), length(xOut)],size(app.histologyIm))
                msgbox(['Hmm, I reading alignment between OCT image and histology, it seems the two don''t overlap. This will result in OCT image not visible on histology.' newline newline 'If this is a mistake (and I think it is) please locate "Along Plane Alignment" panel and set Rotation, X Translation and Z translation to 0.' newline 'Leave Expansion and Shrinage as is.']);
            end
        end
        
        % Fill up cash, for faster loading down the line
        function fillUpOCTFromTifCash(app, src, event)
            setBusyStatus(app, true); 
            ys = app.jsons.sectionIterationConfig.data_um.y.values;
            d_um = abs(app.YPositionumSpinner.Value-ys);
            yI = find(d_um==min(d_um),1,'first');
            
            % Create a grid to load around
            dy = -50:5:50;
            [~,i] = sort(abs(dy));
            dy = dy(i);
            dy(dy==0) = [];
            
            t = tic;
            for i=1:length(dy)
                octFromTifCash(app.jsons.sectionIterationConfig.path,yI+dy(i)); %Fill up cash
                if (toc(t)>2)
                    break; % Out of time to fillup cash
                end
            end
            setBusyStatus(app, false); 
        end
        
        % Get the OCT slide
        function imOCT = getOCTSlide (app)
            ys = app.jsons.sectionIterationConfig.data_um.y.values;
            
            % If outside boundaries, return black image
            if (app.YPositionumSpinner.Value < min(ys) || app.YPositionumSpinner.Value > max(ys))
                imOCT = zeros(2);
                return;
            end
            
            % Which slice is the closest
            d_um = abs(app.YPositionumSpinner.Value-ys);
            yI = find(d_um==min(d_um),1,'first');
            imOCT = octFromTifCash(app.jsons.sectionIterationConfig.path,yI);
        end
        
        % Set busy mode
        function setBusyStatus(app, isBusy) 
            if (isBusy)
                app.Lamp.Color = [1 0 0];
                if ~isempty(app.loadCashTimmer)
                    % Pause timer while we are busy.
                    app.loadCashTimmer.stop();
                end
            else
                app.Lamp.Color = [0 1 0];
                if ~isempty(app.loadCashTimmer)
                    app.loadCashTimmer.start();     
                end
            end
            pause(0.1);
        end
        
        % Helper function to move histology image to OCT
        function [imOCTOut,imHistologyOut,xValues,zValues] = genHistologyOCTFusedImage(app)
            
            % Get transform
            histologyToOCTT = getHist2OCTTransformFromApp(app);
                        
            isMoveHistologyToAlignWithOCT = strcmp(app.BaseImageSwitch.Value,"OCT");
            
            % Get Values based on if we want to draw lines or image
            switch(app.LinesSwitch.Value)
                case 'Off'
                    imOCT = getOCTSlide(app);
                    [imOCTOut,imHistologyOut] = overlayOCTHistology(imOCT,app.histologyIm,histologyToOCTT,...
                        isMoveHistologyToAlignWithOCT);
                case 'On'
                    [imOCT] = generateOverlayPhotobleachedLineOnOCT(app.jsons, ...
                        app.YPositionumSpinner.Value);
                    [imOCTOut,imHistologyOut] = overlayOCTHistology(imOCT,app.FMLinesIm,histologyToOCTT,...
                        isMoveHistologyToAlignWithOCT);
            end
            
            
            if (isMoveHistologyToAlignWithOCT)
                xValues = app.jsons.sectionIterationConfig.data_um.x.values;
                zValues = app.jsons.sectionIterationConfig.data_um.z.values;
            else
                xValues = 1:size(imOCT,2);
                zValues = 1:size(imOCT,1);
            end
        end
        
        % This method draws the main figure containing all data about OCT
        % and histology slides
        function drawFigure(app)
            if isempty(app.jsons.sectionIndexInStack)
                % Nothing was set yet, just return
                return;
            end
            
            imOCT = getOCTSlide(app);
            
            % Present OCT
            imagesc(...
                app.UIAxesOCT, ...
                app.jsons.sectionIterationConfig.data_um.x.values*1e-3,...
                app.jsons.sectionIterationConfig.data_um.z.values*1e-3,...
                imOCT);
            axis(app.UIAxesOCT,'equal');
            axis(app.UIAxesOCT,'ij');
            grid(app.UIAxesOCT,'on');
            set(app.UIAxesOCT,...
                'Layer','top','GridColor',[0.15 0.15 0.7],'GridAlpha',0.25,'XMinorGrid','on','YMinorGrid','on','MinorGridColor',[0.1 0.1 0.5],'MinorGridAlpha',0.35)
            %xlabel(app.UIAxesOCT,'x[mm]')
            %ylabel(app.UIAxesOCT,'z[mm]')
            
            % Convert histology
            [imOCTOut,imHistologyOut,xValuesHIm,zValuesHIm] = genHistologyOCTFusedImage(app);
            
            % Present histology & OCT OVerlay
            % imagesc(hIm,'Parent',app.UIAxesHistology);
            imagesc(...
                app.UIAxesHistology, ...
                xValuesHIm*1e-3,...
                zValuesHIm*1e-3,...
                imOCTOut);
            hold(app.UIAxesHistology,'on');
            alpha = app.TransparancyKnob.Value/100;
            image(...
                'Parent',app.UIAxesHistology, ...
                'XData',xValuesHIm*1e-3,...
                'YData',zValuesHIm*1e-3,...
                'CData',imHistologyOut,...
                'AlphaData',alpha,...
                'CDataMapping','scaled');
            hold(app.UIAxesHistology,'off');
            axis(app.UIAxesHistology,'equal');
            axis(app.UIAxesHistology,'ij');
            grid(app.UIAxesHistology,'on');
            set(app.UIAxesHistology, ...
                'Layer','top','GridColor',[0.15 0.15 0.7],'GridAlpha',0.25,'XMinorGrid','on','YMinorGrid','on','MinorGridColor',[0.1 0.1 0.5],'MinorGridAlpha',0.35);
            %xlabel(app.UIAxesOCT,'x[mm]')
            %ylabel(app.UIAxesOCT,'z[mm]')
            %colormap(app.UIAxesHistology,'gray');
            
            %linkaxes([app.UIAxesOCT app.UIAxesHistology]);
            
            persistent setColormapOnce;
            if isempty(setColormapOnce)
                setColormapOnce = true;
                f = figure(122);
                colormap(app.UIAxesOCT,'gray');
                colormap(app.UIAxesHistology,'gray'); %opens a figure.
                close(f)
            end  
            
            setBusyStatus(app, false); 
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SubjectPathEditField.Value = s3SubjectPath('01'); % Default text is subject path
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            setBusyStatus(app, true);
            
            % Current subject path.
            app.subjectPath = app.SubjectPathEditField.Value;
            
            % Load Jsons
            app.jsons = s3LoadAllSubjectSectionJSONs(app.subjectPath);
                     
            % Add options for user to select section
            app.SectionDropDown.Items = [{'<Choose Section>'}; ...
                app.jsons.stackConfig.data.sections.names(:)];
            app.SectionDropDown.Value = {'<Choose Section>'};
            
            % Bring other parameters to default position, to prevent
            % confusion
            app.YPositionumSpinner.Value = 0;
            app.histologyFilePath = '';
            app.histologyIm = [];
            app.brightFieldFilePath = '';
            app.brightFieldIm = [];
            app.slidePath = '';
            app.FMLinesFilePath = '';
            app.FMLinesIm = [];
            
            setBusyStatus(app, false) 
        end

        % Value changed function: SectionDropDown
        function SectionDropDownValueChanged(app, event)
            % Load a section from drop down list.
            setBusyStatus(app, true);
            
            value = app.SectionDropDown.Value;
            
            % Update JSONs
            app.jsons = s3LoadAllSubjectSectionJSONs(app.subjectPath, '', 'json',app.jsons);
            app.jsons = s3LoadAllSubjectSectionJSONs(app.subjectPath, value, 'json',app.jsons);
            
            if ~isfield(app.jsons.slideConfig.data,'histologyImageFilePath')
                error('%s doesn''t have histology image, did you scan that one?',value);
            end
            
            app.slidePath = awsModifyPathForCompetability([app.subjectPath '/Slides/' value '/']);
            
            % Get Histology Slide path
            app.histologyFilePath = [app.slidePath app.jsons.slideConfig.data.histologyImageFilePath];
            
            % Read histology
            awsSetCredentials;
            % Any fileDatastore request to AWS S3 is limited to 1000 files in 
            % MATLAB 2021a. Due to this bug, we have replaced all calls to 
            % fileDatastore with imageDatastore since the bug does not affect imageDatastore. 
            % 'https://www.mathworks.com/matlabcentral/answers/502559-filedatastore-request-to-aws-s3-limited-to-1000-files'
            ds = imageDatastore(app.histologyFilePath,'ReadFcn',@imread);
            app.histologyIm = ds.read();
            
            % Bright field
            app.brightFieldFilePath = [app.slidePath app.jsons.slideConfig.data.brightFieldImagePath];
            app.brightFieldIm = []; %Don't load now, we might not need it
            
            % Flourecence Image of the lines.
            app.FMLinesFilePath = [app.slidePath app.jsons.slideConfig.data.photobleachedLinesImagePath];
            % Any fileDatastore request to AWS S3 is limited to 1000 files in 
            % MATLAB 2021a. Due to this bug, we have replaced all calls to 
            % fileDatastore with imageDatastore since the bug does not affect imageDatastore. 
            % 'https://www.mathworks.com/matlabcentral/answers/502559-filedatastore-request-to-aws-s3-limited-to-1000-files'
            ds = imageDatastore(app.FMLinesFilePath,'ReadFcn',@imread);
            app.FMLinesIm = ds.read();
            
            % Set knobs to default values
            setKnobesToDefaultPosition(app);
            
            % Finish by drawing image
            drawFigure(app);
            
            % Set up timer to load more in cash
            if isempty(app.loadCashTimmer)
                app.loadCashTimmer = timer('Period',10,'ExecutionMode', 'fixedSpacing', ...
                    'TasksToExecute', Inf);
                app.loadCashTimmer.TimerFcn = @app.fillUpOCTFromTifCash;
                app.loadCashTimmer.start();
            end
        end

        % Value changed function: BaseImageSwitch, 
        % ExpansionorShrinkageSpinner, LinesSwitch, 
        % RotationdegSpinner, TransparancyKnob, 
        % XTranslationumSpinner, YPositionumSpinner, 
        % ZTranslationumSpinner
        function redraw(app, event)
            drawFigure(app);
        end

        % Button pushed function: ReverttoLastSaveButton
        function ReverttoLastSaveButtonPushed(app, event)
            % Set knobs to default values
            setBusyStatus(app, true);
            setKnobesToDefaultPosition(app)
            drawFigure(app);
        end

        % Button pushed function: AutoFineAlignbySurfaceButton
        function AutoFineAlignbySurfaceButtonPushed(app, event)
            setBusyStatus(app, true);
            
            % Load bright field image
            if isempty(app.brightFieldIm)
                % Any fileDatastore request to AWS S3 is limited to 1000 files in 
                % MATLAB 2021a. Due to this bug, we have replaced all calls to 
                % fileDatastore with imageDatastore since the bug does not affect imageDatastore. 
                % 'https://www.mathworks.com/matlabcentral/answers/502559-filedatastore-request-to-aws-s3-limited-to-1000-files'
                ds = imageDatastore(app.brightFieldFilePath,'ReadFcn',@imread);
                app.brightFieldIm = ds.read();
            end
            
            % Make sure tissue interface was marked
            if ~isfield(app.jsons.slideConfig.data.FM,'fiducialLines')
                error('This section doesn''t sem to have marked lines, threfore cannot auto fine align');
            end
            
            % Marked Tissue Surface
            tissueInterfaceI = [app.jsons.slideConfig.data.FM.fiducialLines(:).group] == 't';
            upix = app.jsons.slideConfig.data.FM.fiducialLines(tissueInterfaceI).u_pix;
            vpix = app.jsons.slideConfig.data.FM.fiducialLines(tissueInterfaceI).v_pix;
            
            [upixx, index] = unique(upix);
            vpixx = vpix(index);
            
            [~,i]=sort(upixx);
            markedline = interp1(upixx(i),vpixx(i),1:size(app.brightFieldIm,2),'linear',NaN);
            
            % Set initial Guess 
            histologyToOCTT = getHist2OCTTransformFromApp(app);

            imOCT = getOCTSlide(app);
            imBF  = app.brightFieldIm;
            histologyToOCTT = fineAlignUsingSurface(imOCT, imBF, markedline, true, histologyToOCTT);
            
            % Set knobes
            setKnobesToTransform(app, histologyToOCTT)
            
            drawFigure(app);
        end

        % Button pushed function: SaveSectionButton
        function SaveSectionButtonPushed(app, event)
            % Save everything to the cloud
            setBusyStatus(app, true);
            
            % Generate transform, and top left x and top left z corner in
            % mm
            [histologyToOCTT, octPixelSize_um, topLeftCornerXmm, topLeftCornerZmm] = getHist2OCTTransformFromApp(app);
            
            % Convert best estimate to u,v,h
            [u,v,h] = transformToOctSlide( ...
                histologyToOCTT,app.jsons.sectionIterationConfig.data.new2OriginalAffineTransform, ...
                topLeftCornerXmm, topLeftCornerZmm, octPixelSize_um, app.YPositionumSpinner.Value/1e3);
            
            % See if 'was rectified flag' was used 
            if ( ...
                    isfield(app.jsons.slideConfig.data.FM,'singlePlaneFit_FineAligned') && ...
                    isfield(app.jsons.slideConfig.data.FM.singlePlaneFit_FineAligned,'wasRectified'))
                wasRectified = app.jsons.slideConfig.data.FM.singlePlaneFit_FineAligned.wasRectified;
            else
                wasRectified = false;
            end
            
            if isfield(app.jsons.slideConfig.data.FM,'singlePlaneFit') && ~isempty(app.jsons.slideConfig.data.FM.singlePlaneFit)
                vTypical = app.jsons.slideConfig.data.FM.singlePlaneFit.vTypical;
            else
                vTypical = NaN;
            end
            
            app.jsons.slideConfig.data.FM.singlePlaneFit_FineAligned = spfCreateFromUVH(u,v,h,...
                vTypical, ...
                app.jsons.slideConfig.data.FM.pixelSize_um);
            app.jsons.slideConfig.data.FM.singlePlaneFit_FineAligned.wasRectified = wasRectified;
            app.jsons.slideConfig.data.FMOCTAlignment.FMToOCTTransform = histologyToOCTT;
            app.jsons.slideConfig.data.FMOCTAlignment.planeDistanceFromOrigin_mm = app.YPositionumSpinner.Value/1e3;
            xValues_um = app.jsons.sectionIterationConfig.data_um.x.values;
            zValues_um = app.jsons.sectionIterationConfig.data_um.z.values;
            if std([diff(xValues_um(:)') diff(zValues_um(:)')]) >1e-3
                error('Pixel size in x and z should be the same');
            end
            app.jsons.slideConfig.data.FMOCTAlignment.OCTPixelSize_um = diff(xValues_um(1:2));
            app.jsons.StackConfig.data.noteAboutStackAlignment = 'Fine alignment was run. Go to individual slides config file .FM.singlePlaneFit_FineAligned for the most up-to-date alignment (if exists)';
            
            % Ask user quality questions
            if (isfield(app.jsons.slideConfig.data,'QAInfo'))
                QAInfo = app.jsons.slideConfig.data.QAInfo;
            else
                QAInfo = [];
            end
            app.jsons.slideConfig.data.QAInfo = getImagePairVisualQualityMetric(QAInfo);
            
            % Save images & log - get data
            imOCT = getOCTSlide(app);
            [imOCTOut,imHistologyOut] = overlayOCTHistology(imOCT,app.histologyIm,histologyToOCTT,true);
            
            % Save images & log - save
            %app.jsons.slideConfig.data.alignedImagePath_OCT = 'OCTAligned.tif';
            %app.jsons.slideConfig.data.alignedImagePath_Histology = 'HistologyAligned.tif';
            [linesIm1,alpha] = generateOverlayPhotobleachedLineOnOCT(app.jsons, ...
                        app.YPositionumSpinner.Value);
            [linesIm1,linesIm2] = overlayOCTHistology(linesIm1,app.FMLinesIm,histologyToOCTT,...
                        true);
            alpha = repmat(alpha *0.3,[1 1 3]);
            linesIm = uint8(double(linesIm1).*alpha + repmat(double(linesIm2),[1 1 3]).*(1-alpha));
            updateSlideInS3(imOCTOut,imHistologyOut, ...
                '',...%[app.slidePath app.jsons.slideConfig.data.alignedImagePath_OCT],...
                '',...%[app.slidePath app.jsons.slideConfig.data.alignedImagePath_Histology],...
                [app.subjectPath 'Log/13 Fine Alignment OCT to Histology/' app.SectionDropDown.Value '.png'],...
                app.jsons.sectionIterationConfig.data_um, app.YPositionumSpinner.Value,...
                linesIm);
            
            % Write Jsons update
            awsWriteJSON(app.jsons.slideConfig.data, app.jsons.slideConfig.path);
            awsWriteJSON(app.jsons.stackConfig.data, app.jsons.stackConfig.path);
            
            setBusyStatus(app, false);
        end

        % Value changed function: StepSizeDropDown
        function StepSizeDropDownValueChanged(app, event)
            value = app.StepSizeDropDown.Value;
            app.YPositionumSpinner.Step = sscanf(value,'%d um');
        end

        % Button pushed function: RectifyStackButton
        function RectifyStackButtonPushed(app, event)
            setBusyStatus(app, true);
            rectifyFineAlignedSections(app.subjectPath);
            setBusyStatus(app, false);
            
            % Reload this slide
            SectionDropDownValueChanged(app, []);
        end

        % Button pushed function: ReverttoStackAlignmentButton
        function ReverttoStackAlignmentButtonPushed(app, event)
            % Set knobs to default values
            setBusyStatus(app, true);
            setKnobesToDefaultPosition(app,1)
            drawFigure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FiguresPanel = uipanel(app.UIFigure);
            app.FiguresPanel.Title = 'Figures';
            app.UIAxesOCT = uiaxes(app.FiguresPanel);
            title(app.UIAxesOCT, 'OCT')
            xlabel(app.UIAxesOCT, 'x [mm]')
            ylabel(app.UIAxesOCT, 'z [mm]')
            app.UIAxesHistology = uiaxes(app.FiguresPanel);
            title(app.UIAxesHistology, 'Histology Merge')
            xlabel(app.UIAxesHistology, 'u [mm]')
            ylabel(app.UIAxesHistology, 'v [mm]')
            app.LoadSaveDataPanel = uipanel(app.UIFigure);
            app.LoadSaveDataPanel.Title = 'Load & Save Data';
            app.SubjectPathEditFieldLabel = uilabel(app.LoadSaveDataPanel);
            app.SubjectPathEditFieldLabel.Text = 'Subject Path:';
            app.SubjectPathEditField = uieditfield(app.LoadSaveDataPanel, 'text');
            app.SubjectPathEditField.Value = '<Subject Path to Load>';
            app.LoadButton = uibutton(app.LoadSaveDataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveSectionButton = uibutton(app.LoadSaveDataPanel, 'push');
            app.SaveSectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSectionButtonPushed, true);
            app.SaveSectionButton.Text = 'Save Section';
            app.SectionDropDownLabel = uilabel(app.LoadSaveDataPanel);
            app.SectionDropDownLabel.Text = 'Section:';
            app.SectionDropDown = uidropdown(app.LoadSaveDataPanel);
            app.SectionDropDown.Items = {'<Choose Section>'};
            app.SectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionDropDownValueChanged, true);
            app.SectionDropDown.Value = '<Choose Section>';
            app.Label = uilabel(app.LoadSaveDataPanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.LoadSaveDataPanel);
            app.RectifyStackButton = uibutton(app.LoadSaveDataPanel, 'push');
            app.RectifyStackButton.ButtonPushedFcn = createCallbackFcn(app, @RectifyStackButtonPushed, true);
            app.RectifyStackButton.Text = 'Rectify Stack';
            app.CrossPlaneAlignmentPanel = uipanel(app.UIFigure);
            app.CrossPlaneAlignmentPanel.Title = 'Cross Plane Alignment';
            app.YPositionumSpinnerLabel = uilabel(app.CrossPlaneAlignmentPanel);
            app.YPositionumSpinnerLabel.Text = 'Y Position [um]';
            app.YPositionumSpinner = uispinner(app.CrossPlaneAlignmentPanel);
            app.YPositionumSpinner.Step = 5;
            app.YPositionumSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.StepSizeDropDownLabel = uilabel(app.CrossPlaneAlignmentPanel);
            app.StepSizeDropDownLabel.Text = 'Step Size';
            app.StepSizeDropDown = uidropdown(app.CrossPlaneAlignmentPanel);
            app.StepSizeDropDown.Items = {'1um', '5um', '10um', '20um'};
            app.StepSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @StepSizeDropDownValueChanged, true);
            app.StepSizeDropDown.Value = '5um';
            app.DefaultPositionLabel = uilabel(app.CrossPlaneAlignmentPanel);
            app.DefaultPositionLabel.Text = 'Default Position:';
            app.xumLabel = uilabel(app.CrossPlaneAlignmentPanel);
            app.xumLabel.Text = 'xum';
            app.AlongPlaneAlignmentPanel = uipanel(app.UIFigure);
            app.AlongPlaneAlignmentPanel.Title = 'Along Plane Alignment';
            app.ExpansionorShrinkageLabel = uilabel(app.AlongPlaneAlignmentPanel);
            app.ExpansionorShrinkageLabel.Enable = 'off';
            app.ExpansionorShrinkageLabel.Text = {'Expansion(+) or'; 'Shrinkage(-) [%]'};
            app.ExpansionorShrinkageSpinner = uispinner(app.AlongPlaneAlignmentPanel);
            app.ExpansionorShrinkageSpinner.ValueDisplayFormat = '%0.1f';
            app.ExpansionorShrinkageSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.ExpansionorShrinkageSpinner.Enable = 'off';
            app.RotationdegSpinnerLabel = uilabel(app.AlongPlaneAlignmentPanel);
            app.RotationdegSpinnerLabel.Text = 'Rotation [deg]';
            app.RotationdegSpinner = uispinner(app.AlongPlaneAlignmentPanel);
            app.RotationdegSpinner.Step = 5;
            app.RotationdegSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.XTranslationumSpinnerLabel = uilabel(app.AlongPlaneAlignmentPanel);
            app.XTranslationumSpinnerLabel.Text = 'X Translation [um]';
            app.XTranslationumSpinner = uispinner(app.AlongPlaneAlignmentPanel);
            app.XTranslationumSpinner.Step = 10;
            app.XTranslationumSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.ZTranslationumSpinnerLabel = uilabel(app.AlongPlaneAlignmentPanel);
            app.ZTranslationumSpinnerLabel.Text = 'Z Translation [um]';
            app.ZTranslationumSpinner = uispinner(app.AlongPlaneAlignmentPanel);
            app.ZTranslationumSpinner.Step = 10;
            app.ZTranslationumSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.OverlaySettingsPanel = uipanel(app.UIFigure);
            app.OverlaySettingsPanel.Title = 'Overlay Settings';
            app.TransparancyKnobLabel = uilabel(app.OverlaySettingsPanel);
            app.TransparancyKnobLabel.Text = 'Transparancy';
            app.TransparancyKnob = uiknob(app.OverlaySettingsPanel, 'continuous');
            app.TransparancyKnob.MajorTicks = [0 50 100];
            app.TransparancyKnob.MajorTickLabels = {'OCT', '50/50 Blend', 'Hist'};
            app.TransparancyKnob.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.TransparancyKnob.MinorTicks = [0 25 50 75];
            app.TransparancyKnob.Value = 35;
            app.BaseImageSwitchLabel = uilabel(app.OverlaySettingsPanel);
            app.BaseImageSwitchLabel.Text = 'Base Image';
            app.BaseImageSwitch = uiswitch(app.OverlaySettingsPanel, 'slider');
            app.BaseImageSwitch.Items = {'Histology', 'OCT'};
            app.BaseImageSwitch.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.BaseImageSwitch.Value = 'OCT';
            app.LinesSwitchLabel = uilabel(app.OverlaySettingsPanel);
            app.LinesSwitchLabel.Text = 'Lines';
            app.LinesSwitch = uiswitch(app.OverlaySettingsPanel, 'slider');
            app.LinesSwitch.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.ReverttoLastSaveButton = uibutton(app.UIFigure, 'push');
            app.ReverttoLastSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ReverttoLastSaveButtonPushed, true);
            app.ReverttoLastSaveButton.Text = {'Revert to '; 'Last Save'};
            app.AutoFineAlignbySurfaceButton = uibutton(app.UIFigure, 'push');
            app.AutoFineAlignbySurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFineAlignbySurfaceButtonPushed, true);
            app.AutoFineAlignbySurfaceButton.Text = 'Auto Fine-Align by Surface';
            app.ReverttoStackAlignmentButton = uibutton(app.UIFigure, 'push');
            app.ReverttoStackAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @ReverttoStackAlignmentButtonPushed, true);
            app.ReverttoStackAlignmentButton.Text = {'Revert to '; 'Stack Alignment'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = alignHistology2OCT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end